classdef gui_results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SelectResultsTab              matlab.ui.container.Tab
        AvailableResultsListBoxLabel  matlab.ui.control.Label
        AvailableResultsListBox       matlab.ui.control.ListBox
        AddResultButton               matlab.ui.control.Button
        SelectedResultsListBoxLabel   matlab.ui.control.Label
        SelectedResultsListBox        matlab.ui.control.ListBox
        RefreshButton                 matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        MountForcesButton             matlab.ui.control.Button
        CGDisplacementsButton         matlab.ui.control.Button
        StaticDeflectionsButton       matlab.ui.control.Button
        StaticTestsButton             matlab.ui.control.Button
        KEDButton                     matlab.ui.control.Button
        FrequencyButton               matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        ComparisonButton              matlab.ui.control.Button
        SelectedResultsListBox_2      matlab.ui.control.ListBox
        MountLocationsTab             matlab.ui.container.Tab
        MountLocationsButton          matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        Stage1Tab                     matlab.ui.container.Tab
        UITableLoc1                   matlab.ui.control.Table
        Stage2Tab                     matlab.ui.container.Tab
        UITableLoc2                   matlab.ui.control.Table
        Stage3Tab                     matlab.ui.container.Tab
        UITableLoc3                   matlab.ui.control.Table
        MountStiffnessTab             matlab.ui.container.Tab
        TabGroup2_2                   matlab.ui.container.TabGroup
        Stage1Tab_2                   matlab.ui.container.Tab
        UITableStiff1                 matlab.ui.control.Table
        Stage2Tab_2                   matlab.ui.container.Tab
        UITableStiff2                 matlab.ui.control.Table
        Stage3Tab_2                   matlab.ui.container.Tab
        UITableStiff3                 matlab.ui.control.Table
        MountStiffnessButton          matlab.ui.control.Button
        KEDTab                        matlab.ui.container.Tab
        FrequencyTab                  matlab.ui.container.Tab
        StaticDeflectionsTab          matlab.ui.container.Tab
        MountForcesTab                matlab.ui.container.Tab
        CGDisplacementsTab            matlab.ui.container.Tab
        ResultsLabel                  matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
    end


    properties (Access = private)
         % Description
         
         
    end

    properties (Access = public)
        n=0;%number of selected results
        items % cell array of selected items
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            RefreshButtonPushed(app);


        end

        % Button pushed function: AddResultButton
        function AddResultButtonPushed(app, event)
            app.n=app.n+1;
            app.SelectedResultsListBox.Items(app.n)= {app.AvailableResultsListBox.Value};
            app.items = app.SelectedResultsListBox.Items;
        end

        % Value changed function: AvailableResultsListBox
        function AvailableResultsListBoxValueChanged(app, event)


        end

        % Button pushed function: ComparisonButton
        function ComparisonButtonPushed(app, event)
            figure; hold on;
            k=size(app.items);
            y = zeros(36,k);
            for i=1:k(2)
               y(:,i) = h.opt.x(19:22);
               load(['SavedResults/',app.items{i}]);
               h = eval(app.items{i}(1:end-4));
               y(:,i) = h.opt.x(1:36);
               name = app.items{i}(1:end-8);
               ax.Name = [name,' >> Variation vs Results'];
               value = app.SelectedResultsListBox_2.Value;
               switch value
                   case 'Mount 1 : Stiffness'
                      plot(1:k,y(19:22,:));     
                   case 'Mount 2 : Stiffness'
                      plot(1:k,y(22:24,:)); 
                   case 'Mount 3 : Stiffness'
                      plot(1:k,y(25:27,:));
               end
            
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.SelectedResultsListBox.Items={''}
            
           app.n=0
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
            
            
            k=size(app.items);
            
            for i=1:k(2)
              
               load(['SavedResults/',app.items{i}]);
               
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
               
               
               gui_plot_freq(h,name);
                
            end
            
        end

        % Button pushed function: KEDButton
        function KEDButtonPushed(app, event)
            %KED figure
            k=size(app.items);
            
            for i=1:k(2)
               
               load(['SavedResults/',app.items{i}]);
               
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
               
               
               gui_plot_KED(h,name);
                
            end
        end

        % Button pushed function: MountForcesButton
        function MountForcesButtonPushed(app, event)
            
            k=size(app.items);
            for i=1:k(2)
              
               load(['SavedResults/',app.items{i}]);
               
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
               gui_plot_force(h,name);
                
            end
        end

        % Button pushed function: MountLocationsButton
        function MountLocationsButtonPushed(app, event)
            
            
            figure;    hold on %figure of mount locations
            gui_plot_body;
            
            k=size(app.items);
            leg = "Car Body";    %plot legend
            for i=1:k(2)
                ['SavedResults/',app.items{i}]
               load(['SavedResults/',app.items{i}]);
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
            %                plot_legend=[plot_legend;app.items{i}(1:end-4)];
               leg = gui_plot_loc(h,name,leg);
                               
            end
            [q_TRA,~] = TRA_finder(h.eng.inertia,[0;1;0]); % finding TRA axis
            q_TRA = [0;0;0;q_TRA];
            %             TRA_plotter(q_TRA,h.sus.E_cm);
            E_cm = h.sus.E_cm
                 line([-600*q_TRA(4)+E_cm(1),600*q_TRA(4)+E_cm(1)],...
            [-600*q_TRA(5)+E_cm(2),600*q_TRA(5)+E_cm(2)],...
            [-600*q_TRA(6)+E_cm(3),600*q_TRA(6)+E_cm(3)],'Color','green')
            text(600*q_TRA(4)+E_cm(1),600*q_TRA(5)+E_cm(2),600*q_TRA(6)+E_cm(3),'TRA','color','k')
    
            leg = [leg;"TRA axis"];


            legend(leg);
            loc_vars = {'r1_x';'r1_y';'r1_z';...
                        'r2_x';'r2_y';'r2_z';...
                        'r3_x';'r3_y';'r3_z';...
                        'o1_x';'o1_y';'o1_z';...
                        'o2_x';'o2_y';'o2_z';...
                        'o3_x';'o3_y';'o3_z'};
                    
            loc_dep = num2cell(zeros(18,1));
            loc_purt1 = num2cell(h.stage1.purt(1:18));
            loc_lb1 = num2cell(zeros(18,1));
            loc_ub1 = num2cell(zeros(18,1));
            loc_init1 = num2cell(zeros(18,1));
            loc_opt1 = num2cell(zeros(18,1));
            for i=1:18
               if h.stage0.t2(i)==1 %indepenent
                   loc_dep(i)={'False'};
               else
                   loc_dep(i)={'True'};
               end
               if h.stage1.F(i)==1     %not opt variable
                   loc_lb1(i)={'---'};
                   loc_ub1(i)={'---'};
               else
                   loc_lb1(i)={h.stage1.lb(i)};
                   loc_ub1(i)={h.stage1.ub(i)};
               end
               loc_init1(i) = {h.stage1.init.x(i)};
               loc_opt1(i) = {h.stage1.opt.x(i)};
            end
            
            app.UITableLoc1.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            
            % STAGE 2
            if strcmp(h.stage2.type,'None')==0
            loc_purt1 = num2cell(h.stage2.purt(1:18));loc_purt2 = num2cell(zeros(18,1));loc_purt3 = num2cell(zeros(18,1));
            loc_lb1 = num2cell(zeros(18,1));loc_lb2 = num2cell(zeros(18,1));loc_lb3 = num2cell(zeros(18,1));
            loc_ub1 = num2cell(zeros(18,1));loc_ub2 = num2cell(zeros(18,1));loc_ub3 = num2cell(zeros(18,1));
            loc_init1 = num2cell(zeros(18,1));loc_init2 = num2cell(zeros(18,1));loc_init3 = num2cell(zeros(18,1));
            loc_opt1 = num2cell(zeros(18,1));loc_opt2 = num2cell(zeros(18,1));loc_opt3 = num2cell(zeros(18,1));
            for i=1:18
            %                if h.stage0.t2(i)==1 %indepenent
            %                    loc_dep(i)={'False'};
            %                else
            %                    loc_dep(i)={'True'};
            %                end
               if h.stage2.F(i)==1     %not opt variable
                   loc_lb1(i)={'---'};
                   loc_ub1(i)={'---'};
               else
                   loc_lb1(i)={h.stage2.lb(i)};
                   loc_ub1(i)={h.stage2.ub(i)};
               end
               loc_init1(i) = {h.stage2.init.x(i)};
               loc_opt1(i) = {h.stage2.opt.x(i)};
            end
            
            app.UITableLoc2.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            end
            % STAGE 3
            if strcmp(h.stage3.type,'None')==0
            loc_purt1 = num2cell(h.stage3.purt(1:18));loc_purt2 = num2cell(zeros(18,1));loc_purt3 = num2cell(zeros(18,1));
            loc_lb1 = num2cell(zeros(18,1));loc_lb2 = num2cell(zeros(18,1));loc_lb3 = num2cell(zeros(18,1));
            loc_ub1 = num2cell(zeros(18,1));loc_ub2 = num2cell(zeros(18,1));loc_ub3 = num2cell(zeros(18,1));
            loc_init1 = num2cell(zeros(18,1));loc_init2 = num2cell(zeros(18,1));loc_init3 = num2cell(zeros(18,1));
            loc_opt1 = num2cell(zeros(18,1));loc_opt2 = num2cell(zeros(18,1));loc_opt3 = num2cell(zeros(18,1));
            for i=1:18
            %                if h.stage0.t2(i)==1 %indepenent
            %                    loc_dep(i)={'False'};
            %                else
            %                    loc_dep(i)={'True'};
            %                end
               if h.stage3.F(i)==1     %not opt variable
                   loc_lb1(i)={'---'};
                   loc_ub1(i)={'---'};
               else
                   loc_lb1(i)={h.stage3.lb(i)};
                   loc_ub1(i)={h.stage3.ub(i)};
               end
               loc_init1(i) = {h.stage3.init.x(i)};
               loc_opt1(i) = {h.stage3.opt.x(i)};
            end
            
            app.UITableLoc3.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            end
        end

        % Button pushed function: MountStiffnessButton
        function MountStiffnessButtonPushed(app, event)
            
           
            k=size(app.items);
            for i=1:k(2)
               
               load(['SavedResults/',app.items{i}]);
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
               ax.Name = [name,' >> Stiffness'];
               gui_plot_stiff(h,name);
               
            
            end
            
                 loc_vars = {'k1_x';'k1_y';'k1_z';...
                        'k2_x';'k2_y';'k2_z';...
                        'k3_x';'k3_y';'k3_z';...
                        'c1_x';'c1_y';'c1_z';...
                        'c2_x';'c2_y';'c2_z';...
                        'c3_x';'c3_y';'c3_z'};
                    
            loc_dep = num2cell(zeros(18,1));
            loc_purt1 = num2cell(h.stage1.purt(19:36));
            loc_lb1 = num2cell(zeros(18,1));
            loc_ub1 = num2cell(zeros(18,1));
            loc_init1 = num2cell(zeros(18,1));
            loc_opt1 = num2cell(zeros(18,1));
            for i=19:36
               if h.stage0.t2(i)==1 %indepenent
                   loc_dep(i-18)={'False'};
               else
                   loc_dep(i-18)={'True'};
               end
               if h.stage1.F(i)==1     %not opt variable
                   loc_lb1(i-18)={'---'};
                   loc_ub1(i-18)={'---'};
               else
                   loc_lb1(i-18)={h.stage1.lb(i)};
                   loc_ub1(i-18)={h.stage1.ub(i)};
               end
               loc_init1(i-18) = {h.stage1.init.x(i)};
               loc_opt1(i-18) = {h.stage1.opt.x(i)};
            end
            
            app.UITableStiff1.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            
            % STAGE 2
            if strcmp(h.stage2.type,'None')==0
            loc_purt1 = num2cell(h.stage2.purt(19:36));loc_purt2 = num2cell(zeros(18,1));loc_purt3 = num2cell(zeros(18,1));
            loc_lb1 = num2cell(zeros(18,1));loc_lb2 = num2cell(zeros(18,1));loc_lb3 = num2cell(zeros(18,1));
            loc_ub1 = num2cell(zeros(18,1));loc_ub2 = num2cell(zeros(18,1));loc_ub3 = num2cell(zeros(18,1));
            loc_init1 = num2cell(zeros(18,1));loc_init2 = num2cell(zeros(18,1));loc_init3 = num2cell(zeros(18,1));
            loc_opt1 = num2cell(zeros(18,1));loc_opt2 = num2cell(zeros(18,1));loc_opt3 = num2cell(zeros(18,1));
            for i=19:36
            %                if h.stage0.t2(i)==1 %indepenent
            %                    loc_dep(i)={'False'};
            %                else
            %                    loc_dep(i)={'True'};
            %                end
               if h.stage2.F(i)==1     %not opt variable
                   loc_lb1(i-18)={'---'};
                   loc_ub1(i-18)={'---'};
               else
                   loc_lb1(i-18)={h.stage2.lb(i)};
                   loc_ub1(i-18)={h.stage2.ub(i)};
               end
               loc_init1(i-18) = {h.stage2.init.x(i)};
               loc_opt1(i-18) = {h.stage2.opt.x(i)};
            end
            
            app.UITableStiff2.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            end
            % STAGE 3
            if strcmp(h.stage3.type,'None')==0
            loc_purt1 = num2cell(h.stage3.purt(19:36));loc_purt2 = num2cell(zeros(18,1));loc_purt3 = num2cell(zeros(18,1));
            loc_lb1 = num2cell(zeros(18,1));loc_lb2 = num2cell(zeros(18,1));loc_lb3 = num2cell(zeros(18,1));
            loc_ub1 = num2cell(zeros(18,1));loc_ub2 = num2cell(zeros(18,1));loc_ub3 = num2cell(zeros(18,1));
            loc_init1 = num2cell(zeros(18,1));loc_init2 = num2cell(zeros(18,1));loc_init3 = num2cell(zeros(18,1));
            loc_opt1 = num2cell(zeros(18,1));loc_opt2 = num2cell(zeros(18,1));loc_opt3 = num2cell(zeros(18,1));
            for i=19:36
            %                if h.stage0.t2(i)==1 %indepenent
            %                    loc_dep(i)={'False'};
            %                else
            %                    loc_dep(i)={'True'};
            %                end
               if h.stage3.F(i)==1     %not opt variable
                   loc_lb1(i-18)={'---'};
                   loc_ub1(i-18)={'---'};
               else
                   loc_lb1(i-18)={h.stage3.lb(i)};
                   loc_ub1(i-18)={h.stage3.ub(i)};
               end
               loc_init1(i-18) = {h.stage3.init.x(i)};
               loc_opt1(i-18) = {h.stage3.opt.x(i)};
            end
            
            app.UITableStiff3.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            end
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            mat_files=dir('SavedResults/*_res.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.AvailableResultsListBox.Items(i)={mat_files(i).name};
                
            end
            app.AvailableResultsListBox.Value = app.AvailableResultsListBox.Items(1);
            
        end

        % Value changed function: SelectedResultsListBox
        function SelectedResultsListBoxValueChanged(app, event)
            
            app.items = app.SelectedResultsListBox.Items;
            
        end

        % Button pushed function: StaticDeflectionsButton
        function StaticDeflectionsButtonPushed(app, event)
            figure(5);    hold on; %static deflections figure
            
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)
              
               load(app.items{i});
               plot_legend=[plot_legend;app.items{i}(1:end-4)]
               [K, ~] = stiff_cal(x_opt1_f,eta);
               stc_opt = abs((K/1.5)^(-1)*[0;0;M(1,1)*9.81;0;0;0]);
               st_def_plot(delta_s,stc_opt);
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectResultsTab = uitab(app.TabGroup);
            app.SelectResultsTab.Title = 'Select Results';
            app.AvailableResultsListBoxLabel = uilabel(app.SelectResultsTab);
            app.AvailableResultsListBoxLabel.Text = 'Available Results';
            app.AvailableResultsListBox = uilistbox(app.SelectResultsTab);
            app.AvailableResultsListBox.Items = {};
            app.AvailableResultsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableResultsListBoxValueChanged, true);
            app.AvailableResultsListBox.Value = {};
            app.AddResultButton = uibutton(app.SelectResultsTab, 'push');
            app.AddResultButton.ButtonPushedFcn = createCallbackFcn(app, @AddResultButtonPushed, true);
            app.AddResultButton.Text = 'Add Result';
            app.SelectedResultsListBoxLabel = uilabel(app.SelectResultsTab);
            app.SelectedResultsListBoxLabel.Text = 'Selected Results';
            app.SelectedResultsListBox = uilistbox(app.SelectResultsTab);
            app.SelectedResultsListBox.Items = {};
            app.SelectedResultsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedResultsListBoxValueChanged, true);
            app.SelectedResultsListBox.Value = {};
            app.RefreshButton = uibutton(app.SelectResultsTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DeleteButton = uibutton(app.SelectResultsTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.MountForcesButton = uibutton(app.SelectResultsTab, 'push');
            app.MountForcesButton.ButtonPushedFcn = createCallbackFcn(app, @MountForcesButtonPushed, true);
            app.MountForcesButton.Text = 'Mount Forces';
            app.CGDisplacementsButton = uibutton(app.SelectResultsTab, 'push');
            app.CGDisplacementsButton.Text = 'CG Displacements';
            app.StaticDeflectionsButton = uibutton(app.SelectResultsTab, 'push');
            app.StaticDeflectionsButton.ButtonPushedFcn = createCallbackFcn(app, @StaticDeflectionsButtonPushed, true);
            app.StaticDeflectionsButton.Text = 'Static Deflections';
            app.StaticTestsButton = uibutton(app.SelectResultsTab, 'push');
            app.StaticTestsButton.Text = 'Static Tests';
            app.KEDButton = uibutton(app.SelectResultsTab, 'push');
            app.KEDButton.ButtonPushedFcn = createCallbackFcn(app, @KEDButtonPushed, true);
            app.KEDButton.Text = 'KED';
            app.FrequencyButton = uibutton(app.SelectResultsTab, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Text = 'Frequency';
            app.UIAxes = uiaxes(app.SelectResultsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ComparisonButton = uibutton(app.SelectResultsTab, 'push');
            app.ComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @ComparisonButtonPushed, true);
            app.ComparisonButton.Text = 'Comparison';
            app.SelectedResultsListBox_2 = uilistbox(app.SelectResultsTab);
            app.SelectedResultsListBox_2.Items = {'Mount 1 : Stiffness', 'Mount 2 : Stiffness', 'Mount 3 : Stiffness'};
            app.SelectedResultsListBox_2.Value = 'Mount 1 : Stiffness';
            app.MountLocationsTab = uitab(app.TabGroup);
            app.MountLocationsTab.Title = 'Mount Locations';
            app.MountLocationsButton = uibutton(app.MountLocationsTab, 'push');
            app.MountLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @MountLocationsButtonPushed, true);
            app.MountLocationsButton.Text = 'Mount Locations';
            app.TabGroup2 = uitabgroup(app.MountLocationsTab);
            app.Stage1Tab = uitab(app.TabGroup2);
            app.Stage1Tab.Title = 'Stage 1';
            app.UITableLoc1 = uitable(app.Stage1Tab);
            app.UITableLoc1.ColumnName = {'Variable'; 'Dependency'; 'Purturbation'; 'Upper bound'; 'Lower bound'; 'Initial'; 'Optimized'};
            app.UITableLoc1.RowName = {};
            app.Stage2Tab = uitab(app.TabGroup2);
            app.Stage2Tab.Title = 'Stage 2';
            app.UITableLoc2 = uitable(app.Stage2Tab);
            app.UITableLoc2.ColumnName = {'Variable'; 'Dependency'; 'Purturbation'; 'Upper bound'; 'Lower bound'; 'Initial'; 'Optimized'};
            app.UITableLoc2.RowName = {};
            app.Stage3Tab = uitab(app.TabGroup2);
            app.Stage3Tab.Title = 'Stage 3';
            app.UITableLoc3 = uitable(app.Stage3Tab);
            app.UITableLoc3.ColumnName = {'Variable'; 'Dependency'; 'Purturbation'; 'Upper bound'; 'Lower bound'; 'Initial'; 'Optimized'};
            app.UITableLoc3.RowName = {};
            app.MountStiffnessTab = uitab(app.TabGroup);
            app.MountStiffnessTab.Title = 'Mount Stiffness';
            app.TabGroup2_2 = uitabgroup(app.MountStiffnessTab);
            app.Stage1Tab_2 = uitab(app.TabGroup2_2);
            app.Stage1Tab_2.Title = 'Stage 1';
            app.UITableStiff1 = uitable(app.Stage1Tab_2);
            app.UITableStiff1.ColumnName = {'Variable'; 'Dependency'; 'Purturbation'; 'Upper bound'; 'Lower bound'; 'Initial'; 'Optimized'};
            app.UITableStiff1.RowName = {};
            app.Stage2Tab_2 = uitab(app.TabGroup2_2);
            app.Stage2Tab_2.Title = 'Stage 2';
            app.UITableStiff2 = uitable(app.Stage2Tab_2);
            app.UITableStiff2.ColumnName = {'Variable'; 'Dependency'; 'Purturbation'; 'Upper bound'; 'Lower bound'; 'Initial'; 'Optimized'};
            app.UITableStiff2.RowName = {};
            app.Stage3Tab_2 = uitab(app.TabGroup2_2);
            app.Stage3Tab_2.Title = 'Stage 3';
            app.UITableStiff3 = uitable(app.Stage3Tab_2);
            app.UITableStiff3.ColumnName = {'Variable'; 'Dependency'; 'Purturbation'; 'Upper bound'; 'Lower bound'; 'Initial'; 'Optimized'};
            app.UITableStiff3.RowName = {};
            app.MountStiffnessButton = uibutton(app.MountStiffnessTab, 'push');
            app.MountStiffnessButton.ButtonPushedFcn = createCallbackFcn(app, @MountStiffnessButtonPushed, true);
            app.MountStiffnessButton.Text = 'Mount Stiffness';
            app.KEDTab = uitab(app.TabGroup);
            app.KEDTab.Title = 'KED';
            app.FrequencyTab = uitab(app.TabGroup);
            app.FrequencyTab.Title = 'Frequency';
            app.StaticDeflectionsTab = uitab(app.TabGroup);
            app.StaticDeflectionsTab.Title = 'Static Deflections';
            app.MountForcesTab = uitab(app.TabGroup);
            app.MountForcesTab.Title = 'Mount Forces';
            app.CGDisplacementsTab = uitab(app.TabGroup);
            app.CGDisplacementsTab.Title = 'CG Displacements';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_results

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end